classdef distributions_calculator_with_graph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        grpButton                 matlab.ui.container.ButtonGroup
        btnDistribution           matlab.ui.control.ToggleButton
        btnDensity                matlab.ui.control.ToggleButton
        txtValue2                 matlab.ui.control.NumericEditField
        lblValue2Label            matlab.ui.control.Label
        lblTitle                  matlab.ui.control.Label
        imgFormula                matlab.ui.control.Image
        txtValue3                 matlab.ui.control.NumericEditField
        lblValue3Label            matlab.ui.control.Label
        txtValue1                 matlab.ui.control.NumericEditField
        lblValue1Label            matlab.ui.control.Label
        cbxDistribution           matlab.ui.control.DropDown
        DistribucinDropDownLabel  matlab.ui.control.Label
        RightPanel                matlab.ui.container.Panel
        TabGroup                  matlab.ui.container.TabGroup
        GrficasTab                matlab.ui.container.Tab
        DistributionGraphUI       matlab.ui.control.UIAxes
        DensityGraphUI            matlab.ui.control.UIAxes
        EjercicioTab              matlab.ui.container.Tab
        txtActivity               matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)        
        % Img src Distribución
        Fx_gaussian = 'Fx_Gauss-removebg-preview.png';
        Fx_poisson = 'Fx_Poisson-removebg-preview.png';
        Fx_binomial = 'Fx_Binomial-removebg-preview.png';
        Fx_Uniforme = 'Fx_Uniforme-removebg-preview.png';
        Fx_Exponencial = 'Fx_Exponencial-removebg-preview.png';

        % Img src Densidad
        f_gaussian = 'f_Gauss-removebg-preview.png';
        f_poisson = 'f_Poisson-removebg-preview.png';
        f_binomial = 'f_Binomial-removebg-preview.png';
        f_Uniforme = 'f_Uniforme-removebg-preview.png';
        f_Exponencial = 'f_Exponencial-removebg-preview.png';

    end
    
    methods (Access = private)
        % Gauss
        function pdf = gaussianpdf(~, mu, sigma, x)
            pdf = (1/(sigma * sqrt(2*pi))) * exp(-(1/2)*((x - mu)/sigma).^2);
        end

        function cdf = gaussiancdf(~, mu, sigma, x)
            cdf = 0.5 * (1+erf((x - mu) / (sigma * sqrt(2))));
        end

        % Poisson
        function pdf = poissonpdf(~,k, lambda)
            pdf = exp(-lambda) * lambda.^k ./ factorial(k);
        end

        function cdf = poissoncdf(~,k, lambda, pdf)
            cdf = zeros(size(k));
            for i = 1:length(k)
                cdf(i) = sum(pdf(1:i));
            end
        end

        % Binomial
        function pdf = binopdf(~, k, n, p)
            coeff = factorial(n) ./ (factorial(k) .* factorial(n - k));
            pdf = coeff .* p.^k .* (1 - p).^(n - k);
        end
    
        function cdf = binocdf(app, k, n, p)
            pdf = app.binopdf(k, n, p);
            cdf = cumsum(pdf);
        end

        % Uniforme
        function pdf = uniformepdf(~, x, b, a)
            pdf =  1/(b-a) * (x>=a & x<=b);
        end
        
        function cdf = uniformecdf(~, x, b, a)
            cdf = (x - a) / (b - a);
            cdf(x < a) = 0;
            cdf(x > b) = 1;
        end

        % Exponencial
        function pdf = exppdf(~, x, lambda)
            pdf = lambda * exp(-lambda * x);
        end
    
        function cdf = expcdf(~, x, lambda)
            cdf = 1 - exp(-lambda * x);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % inicialización default para Gaussiana
            app.lblValue1Label.Text = 'u';
            app.lblValue2Label.Text = 'σ';

            % Ejercicio
            app.txtActivity.Value = sprintf('Una fábrica de producción de agua embotellada, cuenta con una máquina de envasado automático, la cual vierte en cada botella una cierta cantidad de agua que sigue una distribución normal con media de 500 mililitros y una varianza de 25 mililitros. \n\n¿Qué porcentaje de las botellas se llenan con agua entre 490 y 507 mililitros?');
            
            app.lblTitle.Text = 'Densidad Gaussiana';
            app.imgFormula.ImageSource = app.f_gaussian;

            app.txtValue3.Visible = "off";
            app.lblValue3Label.Visible = "off";

        end

        % Callback function: LeftPanel, cbxDistribution
        function cbxDistributionOnChange(app, event)
            distributionType = app.cbxDistribution.Value;
            selectedButton = app.grpButton.SelectedObject;

            switch distributionType
                case 'Gaussiana'

                    % Ejercicio                   
                    app.txtActivity.Value = sprintf('Una fábrica de producción de agua embotellada, cuenta con una máquina de envasado automático, la cual vierte en cada botella una cierta cantidad de agua que sigue una distribución normal con media de 500 mililitros y una varianza de 25 mililitros. \n\n¿Qué porcentaje de las botellas se llenan con agua entre 490 y 507 mililitros?');

                    % Activación de los labels necesarios.
                    app.lblValue1Label.Visible = 'on';
                    app.txtValue1.Visible = 'on';
                    app.lblValue2Label.Visible = 'on';
                    app.txtValue2.Visible = 'on';
                    app.lblValue3Label.Visible = 'off';
                    app.txtValue3.Visible = 'off';

                    app.lblValue1Label.Text = 'u';
                    app.lblValue2Label.Text = 'σ';

                    % Verifica titulo de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución Gaussiana';
                        app.imgFormula.ImageSource = app.Fx_gaussian;
                    else
                        app.lblTitle.Text = 'Densidad Gaussiana';
                        app.imgFormula.ImageSource = app.f_gaussian;
                    end

                case 'Poisson'
                    app.txtActivity.Value = sprintf('La veterinaria de Jorge recibe un promedio de μ = 4 pacientes por día. Sabiendo que el número de pacientes que llegan en un día sigue una distribución de Poisson, calcular: \n\na) la probabilidad de que lleguen 3 pacientes en un día.');
                    % Activación de los labels necesarios.
                    app.lblValue1Label.Visible = 'on';
                    app.txtValue1.Visible = 'on';
                    app.lblValue2Label.Visible = 'on';
                    app.txtValue2.Visible = 'on';
                    app.lblValue3Label.Visible = 'on';
                    app.txtValue3.Visible = 'on';

                    app.lblValue1Label.Text = 'K mín';
                    app.lblValue2Label.Text = 'K Max';
                    app.lblValue3Label.Text = 'Lambda';

                    % Verifica titulo y img de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución de Poisson';
                        app.imgFormula.ImageSource = app.Fx_poisson;
                    else
                        app.lblTitle.Text = 'Densidad de Poisson';
                        app.imgFormula.ImageSource = app.f_gaussian;
                    end
                case 'Binomial'
                    app.txtActivity.Value = sprintf('Preparo unas cosas riquísimas, pasteles, pizza, agua helada, pero lo más vendido son las matehamburguesas, las únicas hamburguesas que se venden con papas y leche chocolatada. \n\nLa probabilidad de que a un cliente nuevo le guste la matehamburguesa es de 0,8. Si vienen 3 nuevos clientes a mí cafetería ¿cuál será la probabilidad de que solo a dos de ellos les guste la hamburguesa?');
                    % Activación de los labels necesarios.
                    app.lblValue1Label.Visible = 'on';
                    app.txtValue1.Visible = 'on';
                    app.lblValue2Label.Visible = 'on';
                    app.txtValue2.Visible = 'on';
                    app.lblValue3Label.Visible = 'off';
                    app.txtValue3.Visible = 'off';

                    % contenido binomial
                    app.lblValue1Label.Text = 'N';
                    app.lblValue2Label.Text = 'p';

                    % Verifica titulo y img de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución Binomial';
                        app.imgFormula.ImageSource = app.Fx_binomial;
                    else
                        app.lblTitle.Text = 'Densidad Binomial';
                        app.imgFormula.ImageSource = app.f_binomial;
                    end
                case 'Uniforme'
                    app.txtActivity.Value = sprintf('Se sabe que el peso de las uvas se distribuye siempre de forma equiprobable entre 6 y 10 gramos. Determina la función de densidad del pseo de las uvas y el peso medio de las uvas.');
                    % Activación de los labels necesarios.
                    app.lblValue1Label.Visible = 'on';
                    app.txtValue1.Visible = 'on';
                    app.lblValue2Label.Visible = 'on';
                    app.txtValue2.Visible = 'on';

                    app.lblValue1Label.Text = 'a';
                    app.lblValue2Label.Text = 'b';

                    app.lblValue3Label.Visible = "off"
                    app.txtValue3.Visible = 'off';

                    % Verifica titulo y img de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución Uniforme';
                        app.imgFormula.ImageSource = app.Fx_Uniforme;
                    else
                        app.lblTitle.Text = 'Densidad Uniforme';
                        app.imgFormula.ImageSource = app.f_Uniforme;
                    end
                case 'Exponencial'
                    app.txtActivity.Value = sprintf('El tiempo de vida de una lámpara especial sigue una distribución exponencial con media 100 hs. \n\na) ¿Cuál es la probabilidad de que una lámpara dure por lo menos 30 horas?');
                    app.lblValue1Label.Visible = 'on';
                    app.txtValue1.Visible = 'on';
                    app.lblValue2Label.Visible = 'off';
                    app.txtValue2.Visible = 'off';
                    app.lblValue3Label.Visible = 'off';
                    app.txtValue3.Visible = 'off';

                    % contenido binomial
                    app.lblValue1Label.Text = 'x';

                    % Verifica titulo y img de formula
                    if strcmp(selectedButton.Text, 'Distribución')
                        app.lblTitle.Text = 'Distribución Exponencial';
                        app.imgFormula.ImageSource = app.Fx_Exponencial;
                    else
                        app.lblTitle.Text = 'Densidad Exponencial';
                        app.imgFormula.ImageSource = app.f_Exponencial;
                    end
            end
        end

        % Selection changed function: grpButton
        function grpButtonSelectionChanged(app, event)
            selectedButton = app.grpButton.SelectedObject;
            selectedOption = app.cbxDistribution.Value;

            if strcmp(selectedButton.Text, 'Distribución')
                switch selectedOption
                    case 'Gaussiana'
                        app.lblTitle.Text = 'Distribución Gaussiana';
                        app.imgFormula.ImageSource = app.Fx_gaussian;
                    case 'Poisson'
                        app.lblTitle.Text = 'Distribución de Poisson';
                        app.imgFormula.ImageSource = app.Fx_poisson;
                    case 'Binomial'
                        app.lblTitle.Text = 'Distribución Binomial';
                        app.imgFormula.ImageSource = app.Fx_binomial;
                    case 'Uniforme'
                        app.lblTitle.Text = 'Distribución Uniforme';
                        app.imgFormula.ImageSource = app.Fx_Uniforme;
                    case 'Exponencial'
                        app.lblTitle.Text = 'Distribución Exponencial';
                        app.imgFormula.ImageSource = app.Fx_Exponencial;
                end
            else
                switch selectedOption
                    case 'Gaussiana'
                        app.lblTitle.Text = 'Densidad Gaussiana';
                        app.imgFormula.ImageSource = app.f_gaussian;
                    case 'Poisson'
                        app.lblTitle.Text = 'Densidad de Poisson';
                        app.imgFormula.ImageSource = app.f_poisson;
                    case 'Binomial'
                        app.lblTitle.Text = 'Densidad Binomial';
                        app.imgFormula.ImageSource = app.f_binomial;
                    case 'Uniforme'
                        app.lblTitle.Text = 'Densidad Uniforme';
                        app.imgFormula.ImageSource = app.f_Uniforme;
                    case 'Exponencial'
                        app.lblTitle.Text = 'Densidad Exponencial';
                        app.imgFormula.ImageSource = app.f_Exponencial;
                end
            end
        end

        % Value changed function: txtValue1, txtValue2, txtValue3
        function txtValueChanged(app, event)
            % Obtener los valores actualizados
            value1 = app.txtValue1.Value;
            value2 = app.txtValue2.Value;
            value3 = app.txtValue3.Value;
            distributionType = app.cbxDistribution.Value;
    
            switch distributionType
                case 'Gaussiana'
                    mu = value1;
                    sigma = value2;
                    x = linspace(0, 10, 20);
    
                    % CDF
                    cdf = app.gaussiancdf(mu, sigma, x);
                    stairs(app.DistributionGraphUI, x, cdf);
                    title(app.DistributionGraphUI, 'Función de Distribución de Probabilidad Gaussiana');
                    xlabel(app.DistributionGraphUI, 'x');
                    ylabel(app.DistributionGraphUI, 'f(x)');
                    grid(app.DistributionGraphUI, 'on');
    
                    % PDF
                    pdf = app.gaussianpdf(mu, sigma, x);
                    bar(app.DensityGraphUI, x, pdf);
                    title(app.DensityGraphUI, 'Función de Densidad de Probabilidad Gaussiana');
                    xlabel(app.DensityGraphUI, 'x');
                    ylabel(app.DensityGraphUI, 'f(x)');
                    grid(app.DensityGraphUI, 'on');
    
                case 'Poisson'
                    kMin = value1;
                    kMax = value2;
                    lambda = value3;
        
                    k = kMin:kMax;
    
                    % PDF
                    pdf = app.poissonpdf(k,lambda);
                    
                    bar(app.DensityGraphUI, k, pdf);
                    title(app.DensityGraphUI, 'Función de Densidad de Probabilidad de Poisson');
                    xlabel(app.DensityGraphUI, 'k');
                    ylabel(app.DensityGraphUI, 'f(x) = P(X = k)');
                    grid(app.DensityGraphUI, 'on');
    
                    % CDF
                    cdf = app.poissoncdf(k, lambda, pdf);
                    
                    stairs(app.DistributionGraphUI, k, cdf);
                    title(app.DistributionGraphUI, 'Función de Distribución Acumulativa de Poisson');
                    xlabel(app.DistributionGraphUI, 'x');
                    ylabel(app.DistributionGraphUI, 'F(x) = P(X <= x)');
                    grid(app.DistributionGraphUI, 'on');
                case 'Binomial'
                    n = value1;    % intentos 10
                    p = value2;   % Probabilidad de éxito 0.3
                    k = 0:n;   % Total
                    
                    cdf = app.binocdf(k, n, p);
                    
                    stairs(app.DistributionGraphUI, k, cdf);
                    title(app.DistributionGraphUI, 'Función de Distribución de Probabilidad Binomial');
                    xlabel(app.DistributionGraphUI, 'x');
                    ylabel(app.DistributionGraphUI, 'f(x)');
                    grid(app.DistributionGraphUI, 'on');
    
                    % PDF
                    
                    pdf = app.binopdf(k,n,p);
    
                    bar(app.DensityGraphUI, k, pdf);
                    title(app.DensityGraphUI, 'Función de Densidad de Probabilidad Binomial');
                    xlabel(app.DensityGraphUI, 'k');
                    ylabel(app.DensityGraphUI, 'f(x) = P(X = k)');
                    grid(app.DensityGraphUI, 'on');
        
                case 'Uniforme'
                    % PDF
                    a = value1;
                    b = value2;
                    x = linspace(a-1,b+1,1000);
                  
                    pdf = app.uniformepdf(x,b,a);
    
                    plot(app.DensityGraphUI, x, pdf, 'LineWidth', 2, 'Marker', 'none');
                    title(app.DensityGraphUI, 'Función de Densidad de Probabilidad Uniforme');
                    xlabel(app.DensityGraphUI, 'k');
                    ylabel(app.DensityGraphUI, 'f(x) = P(X = k)');
                    grid(app.DensityGraphUI, 'on');
    
                    % CDF
                    cdf = app.uniformecdf(x,b,a);
    
                    stairs(app.DistributionGraphUI, x, cdf);
                    title(app.DistributionGraphUI, 'Función de Distribución de Probabilidad Uniforme');
                    xlabel(app.DistributionGraphUI, 'x');
                    ylabel(app.DistributionGraphUI, 'f(x)');
                    grid(app.DistributionGraphUI, 'on');
    
                case 'Exponencial'
                    x = value1;
                    lambda = 1 / x;
                    
                    desviacion_estandar = x / 3;
                    x_min = 0;
                    x_max = x + 3 * desviacion_estandar;
    
                    % Visualizacion:
                    x_visual = linspace(x_min, x_max, 1000);
                
                    % PDF               
                    pdf = app.exppdf(x_visual, lambda);
    
                    plot(app.DensityGraphUI, x_visual, pdf);
                    title(app.DensityGraphUI, 'Función de Densidad de Probabilidad Exponencial');
                    xlabel(app.DensityGraphUI, 'k');
                    ylabel(app.DensityGraphUI, 'f(x) = P(X = k)');
                    grid(app.DensityGraphUI, 'on');
    
                    % CDF
                    cdf = app.expcdf(x_visual, lambda);
    
                    stairs(app.DistributionGraphUI, x_visual, cdf);
                    title(app.DistributionGraphUI, 'Función de Distribución de Probabilidad Exponencial');
                    xlabel(app.DistributionGraphUI, 'x');
                    ylabel(app.DistributionGraphUI, 'f(x)');
                    grid(app.DistributionGraphUI, 'on');
    
                otherwise
                    % Texto de error
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {469, 469};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.ButtonDownFcn = createCallbackFcn(app, @cbxDistributionOnChange, true);
            app.DistribucinDropDownLabel = uilabel(app.LeftPanel);
            app.DistribucinDropDownLabel.Text = 'Distribución';
            app.cbxDistribution = uidropdown(app.LeftPanel);
            app.cbxDistribution.Items = {'Gaussiana', 'Poisson', 'Binomial', 'Uniforme', 'Exponencial'};
            app.cbxDistribution.ValueChangedFcn = createCallbackFcn(app, @cbxDistributionOnChange, true);
            app.cbxDistribution.Tag = 'gauss';
            app.cbxDistribution.Value = 'Gaussiana';
            app.lblValue1Label = uilabel(app.LeftPanel);
            app.lblValue1Label.Text = 'lblValue1';
            app.txtValue1 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue1.ValueChangedFcn = createCallbackFcn(app, @txtValueChanged, true);
            app.txtValue1.Tag = 'miu';
            app.lblValue3Label = uilabel(app.LeftPanel);
            app.lblValue3Label.Text = 'lblValue3';
            app.txtValue3 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue3.ValueChangedFcn = createCallbackFcn(app, @txtValueChanged, true);
            app.txtValue3.Tag = 'sigma';
            app.imgFormula = uiimage(app.LeftPanel);
            app.lblTitle = uilabel(app.LeftPanel);
            app.lblTitle.Text = 'lblTitle';
            app.lblValue2Label = uilabel(app.LeftPanel);
            app.lblValue2Label.Text = 'lblValue2';
            app.txtValue2 = uieditfield(app.LeftPanel, 'numeric');
            app.txtValue2.ValueChangedFcn = createCallbackFcn(app, @txtValueChanged, true);
            app.txtValue2.Tag = 'miu';
            app.grpButton = uibuttongroup(app.LeftPanel);
            app.grpButton.SelectionChangedFcn = createCallbackFcn(app, @grpButtonSelectionChanged, true);
            app.grpButton.Title = 'Función';
            app.btnDensity = uitogglebutton(app.grpButton);
            app.btnDensity.Text = 'Densidad';
            app.btnDensity.Value = true;
            app.btnDistribution = uitogglebutton(app.grpButton);
            app.btnDistribution.Text = 'Distribución';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.GrficasTab = uitab(app.TabGroup);
            app.GrficasTab.Title = 'Gráficas';
            app.DensityGraphUI = uiaxes(app.GrficasTab);
            title(app.DensityGraphUI, 'f(x)')
            xlabel(app.DensityGraphUI, 'X')
            ylabel(app.DensityGraphUI, 'Y')
            app.DistributionGraphUI = uiaxes(app.GrficasTab);
            title(app.DistributionGraphUI, 'F(x)')
            xlabel(app.DistributionGraphUI, 'X')
            ylabel(app.DistributionGraphUI, 'Y')
            app.EjercicioTab = uitab(app.TabGroup);
            app.EjercicioTab.Title = 'Ejercicio';
            app.txtActivity = uitextarea(app.EjercicioTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = distributions_calculator_with_graph

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end